classdef ElectroApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        ElectroAppTringulodeTresCargasLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        q1Field_6                     matlab.ui.control.NumericEditField
        q1CLabel_6                    matlab.ui.control.Label
        q1Field_5                     matlab.ui.control.NumericEditField
        q1CLabel_5                    matlab.ui.control.Label
        q1Field_4                     matlab.ui.control.NumericEditField
        q1CLabel_4                    matlab.ui.control.Label
        q1Field_3                     matlab.ui.control.NumericEditField
        q1CLabel_3                    matlab.ui.control.Label
        q1Field_2                     matlab.ui.control.NumericEditField
        q1CLabel_2                    matlab.ui.control.Label
        Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel  matlab.ui.control.Label
        aField                        matlab.ui.control.NumericEditField
        amLabel                       matlab.ui.control.Label
        CalculartrabajoButton         matlab.ui.control.Button
        CalcularcampoelectricoButton  matlab.ui.control.Button
        q3Field                       matlab.ui.control.NumericEditField
        q3CLabel                      matlab.ui.control.Label
        q2Field                       matlab.ui.control.NumericEditField
        q2CLabel                      matlab.ui.control.Label
        q1Field                       matlab.ui.control.NumericEditField
        q1CLabel                      matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Panel                         matlab.ui.container.Panel
        ResultadoCampo                matlab.ui.control.Label
        ResultadosdelcampoelctricoLabel  matlab.ui.control.Label
        ResultadoLabel                matlab.ui.control.Label
        TablaCampo                    matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularcampoelectricoButton
        function CalcularcampoelectricoButtonPushed(app, event)


        % ----- Obtener valores (en µC, convertir a C) -----
        q1 = double(app.q1Field.Value) * 1e-6;  % Carga 1 en Coulombs
        q2 = double(app.q2Field.Value) * 1e-6;  % Carga 2 en Coulombs
        q3 = double(app.q3Field.Value) * 1e-6;  % Carga 3 en Coulombs
        
        % ----- Parámetros del sistema -----
        a = double(app.aField.Value);            % Lado del triángulo equilátero en metros
        y = double(app.PosicionP);               % Posición de P
        ke = 9e9;                                % Constante de Coulomb [N·m^2/C^2]
        
        % ----- Altura del triángulo equilátero -----
        h = (sqrt(3) / 2) * a;
        
        % ----- Distancias desde P a cada carga -----
        r1 = sqrt((a / 2)^2 + y^2);             % Distancia de P a q1
        r2 = sqrt((a / 2)^2 + y^2);             % Distancia de P a q2
        r3 = abs(y - h);                         % Distancia de P a q3 (vertical)
        
        % ----- Ángulos para q1 y q2 respecto a la vertical -----
        cos_angulo = y / r1;                    % Componente coseno del ángulo
        sen_angulo = (a / 2) / r1;               % Componente seno del ángulo
        
        % ----- Cálculo de campos eléctricos (con signo) -----
        E1_mag = ke * q1 / r1^2;                 % Campo eléctrico de q1 (con signo)
        E2_mag = ke * q2 / r2^2;                 % Campo eléctrico de q2 (con signo)
        E3_mag = ke * q3 / r3^2;                 % Campo eléctrico de q3 (con signo)
        
        % ----- Componentes del campo eléctrico de q1 y q2 -----
        % q1 está en (-a/2, 0), q2 en (a/2, 0), q3 en (0, h), P está en (0, y)
        
        % Componente x de los campos eléctricos
        Ex_q1 = E1_mag * sen_angulo;             % Campo x de q1: positiva
        Ex_q2 = -E2_mag * sen_angulo;            % Campo x de q2: negativa
        Ex_q3 = 0;                                % q3 está sobre el eje y
        
        % Componente y de los campos eléctricos
        Ey_q1 = E1_mag * cos_angulo;             % Campo y de q1: positiva
        Ey_q2 = E2_mag * cos_angulo;             % Campo y de q2: positiva
        Ey_q3 = E3_mag;                          % Campo y de q3: ya tiene signo negativo
        
        % ----- Componentes totales del campo eléctrico -----
        Ex_total = Ex_q1 + Ex_q2 + Ex_q3;        % Componente x total
        Ey_total = Ey_q1 + Ey_q2 + Ey_q3;        % Componente y total
        
        % ----- Magnitud total del campo eléctrico en P -----
        E_total = sqrt(Ex_total.^2 + Ey_total.^2);
        
        % ----- Mostrar resultado -----
        app.ResultadoCampo.Text = sprintf('Campo (E): %.2f N/C', E_total);

        % Dibujar en el TablaCampo    
        
        % Generar valores de distancia
        a_values = linspace(0.05, 1, 100); % de 5 cm a 1 m
        E_values = campoElectrico(a_values, q1, q2, q3, y);
        
        % Graficar los resultados
        plot(app.TablaCampo, a_values, E_values, 'y--', 'LineWidth', 2);
        xlabel(app.TablaCampo, 'Distancia a (m)');
        ylabel(app.TablaCampo, 'Campo (N/C)');
        title(app.TablaCampo, '');
        grid(app.TablaCampo, 'on');
        
        % Ajustes adicionales
        xlim(app.TablaCampo, [0.05, 1]); % Limitar el eje x
        ylim(app.TablaCampo, [min(E_values), max(E_values)]); % Ajustar el eje y
        legend(app.TablaCampo, 'Campo Eléctrico');


        end

        % Button pushed function: CalculartrabajoButton
        function CalculartrabajoButtonPushed(app, event)
            q1 = app.q1Field.Value * 1e-6;
            q2 = app.q2Field.Value * 1e-6;
            q3 = app.q3Field.Value * 1e-6;
            a  = app.aField.Value; % metros

            % Llamar al script del trabajo
            W = trabajoCargas(q1, q2, q3, a);
        
            % Mostrar resultado 
           app.ResultadoLabel.Text = ['Trabajo: ' num2str(W) ' J'];

             % gráfica
            k = 9e9;
            a_values = linspace(0.05, 1, 100); % de 5 cm a 1 m
            W_values = k * (q1*q2 + q1*q3 + q2*q3) ./ a_values;
        
            % Dibujar en el UIAxes
            plot(app.UIAxes, a_values, W_values, 'r-', 'LineWidth', 2);
            xlabel(app.UIAxes, 'Distancia a (m)');
            ylabel(app.UIAxes, 'Trabajo W (J)');
            title(app.UIAxes, 'Trabajo en función de la distancia a');
            grid(app.UIAxes, 'on');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.TablaCampo = uiaxes(app.Panel);
            title(app.TablaCampo, 'Title')
            xlabel(app.TablaCampo, 'X')
            ylabel(app.TablaCampo, 'Y')
            zlabel(app.TablaCampo, 'Z')
            app.TablaCampo.SubtitleFontWeight = 'bold';
            app.ResultadoLabel = uilabel(app.Panel);
            app.ResultadoLabel.Text = 'Trabajo (W): 0,00 J';
            app.ResultadosdelcampoelctricoLabel = uilabel(app.Panel);
            app.ResultadosdelcampoelctricoLabel.Text = 'Campo en función de la distancia a (∣E∣):';
            app.ResultadoCampo = uilabel(app.Panel);
            app.ResultadoCampo.Text = 'Campoo (E): 0,00 N/C';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagen.png');
            app.q1CLabel = uilabel(app.Panel_2);
            app.q1CLabel.Text = 'q1 (µC):';
            app.q1Field = uieditfield(app.Panel_2, 'numeric');
            app.q2CLabel = uilabel(app.Panel_2);
            app.q2CLabel.Text = 'q2 (µC) :';
            app.q2Field = uieditfield(app.Panel_2, 'numeric');
            app.q3CLabel = uilabel(app.Panel_2);
            app.q3CLabel.Text = 'q3 (µC): ';
            app.q3Field = uieditfield(app.Panel_2, 'numeric');
            app.CalcularcampoelectricoButton = uibutton(app.Panel_2, 'push');
            app.CalcularcampoelectricoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularcampoelectricoButtonPushed, true);
            app.CalcularcampoelectricoButton.Text = 'Calcular campo electrico';
            app.CalculartrabajoButton = uibutton(app.Panel_2, 'push');
            app.CalculartrabajoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculartrabajoButtonPushed, true);
            app.CalculartrabajoButton.Text = 'Calcular trabajo';
            app.amLabel = uilabel(app.Panel_2);
            app.amLabel.Text = 'a (m): ';
            app.aField = uieditfield(app.Panel_2, 'numeric');
            app.Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel = uilabel(app.Panel_2);
            app.Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel.Text = '"Tres cargas puntuales se ubican en los vértices de un triángulo equilátero ( q1,q2: positivas y q3 negativa). La aplicación calcula el campo eléctrico a lo largo de la altura y el trabajo necesario para ensamblar el sistema, a partir de los valores de las cargas y la distancia entre ellas."';
            app.q1CLabel_2 = uilabel(app.Panel_2);
            app.q1CLabel_2.Text = 'y1';
            app.q1Field_2 = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_3 = uilabel(app.Panel_2);
            app.q1CLabel_3.Text = 'y2';
            app.q1Field_3 = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_4 = uilabel(app.Panel_2);
            app.q1CLabel_4.Text = 'y3';
            app.q1Field_4 = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_5 = uilabel(app.Panel_2);
            app.q1CLabel_5.Text = 'y4';
            app.q1Field_5 = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_6 = uilabel(app.Panel_2);
            app.q1CLabel_6.Text = 'y5';
            app.q1Field_6 = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.ElectroAppTringulodeTresCargasLabel = uilabel(app.Panel_3);
            app.ElectroAppTringulodeTresCargasLabel.Text = 'ElectroApp: Triángulo de Tres Cargas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectroApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end